classdef GUI_Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CameraTab                     matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        ResolutionDropDown            matlab.ui.control.DropDown
        ResolutionDropDownLabel       matlab.ui.control.Label
        FocusSlider                   matlab.ui.control.Slider
        FocusSliderLabel              matlab.ui.control.Label
        ExposureSlider                matlab.ui.control.Slider
        ExposureSliderLabel           matlab.ui.control.Label
        ContrastSlider                matlab.ui.control.Slider
        ContrastSliderLabel           matlab.ui.control.Label
        BrightnessSlider              matlab.ui.control.Slider
        BrightnessSliderLabel         matlab.ui.control.Label
        CameraSettingsLabel           matlab.ui.control.Label
        SegmentationTab               matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        SegmentationLabel             matlab.ui.control.Label
        UIAxesSeg                     matlab.ui.control.UIAxes
        UIAxesPiece                   matlab.ui.control.UIAxes
        FeaturesTab                   matlab.ui.container.Tab
        CoordinateandRealibiltyLabel  matlab.ui.control.Label
        MatchingFeaturesLabel         matlab.ui.control.Label
        UIAxesFeatures                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam % Webcam
        interactiveApp % the main gui app handle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cam, interactiveApp)
            app.cam = cam;
            app.interactiveApp = interactiveApp;
        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)
            app.cam.Resolution = app.ResolutionDropDown.Value;            
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            app.cam.Brightness = floor(app.BrightnessSlider.Value);            
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            app.cam.Contrast = floor(app.ContrastSlider.Value);            
        end

        % Value changed function: ExposureSlider
        function ExposureSliderValueChanged(app, event)
            app.cam.Exposure = floor(app.ExposureSlider.Value);
        end

        % Value changed function: FocusSlider
        function FocusSliderValueChanged(app, event)
            app.cam.Focus = app.FocusSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.GridLayout = uigridlayout(app.CameraTab);
            app.CameraSettingsLabel = uilabel(app.GridLayout);
            app.CameraSettingsLabel.Text = 'Camera Settings';
            app.BrightnessSliderLabel = uilabel(app.GridLayout);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.GridLayout);
            app.BrightnessSlider.MajorTicks = [0 50 100];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.ContrastSliderLabel = uilabel(app.GridLayout);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.GridLayout);
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ExposureSliderLabel = uilabel(app.GridLayout);
            app.ExposureSliderLabel.Text = 'Exposure';
            app.ExposureSlider = uislider(app.GridLayout);
            app.ExposureSlider.Limits = [-11 0];
            app.ExposureSlider.MajorTicks = [-11 0];
            app.ExposureSlider.ValueChangedFcn = createCallbackFcn(app, @ExposureSliderValueChanged, true);
            app.ExposureSlider.MinorTicks = [-11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1];
            app.ExposureSlider.Value = -5;
            app.FocusSliderLabel = uilabel(app.GridLayout);
            app.FocusSliderLabel.Text = 'Focus';
            app.FocusSlider = uislider(app.GridLayout);
            app.FocusSlider.MajorTicks = [0 50 100];
            app.FocusSlider.ValueChangedFcn = createCallbackFcn(app, @FocusSliderValueChanged, true);
            app.ResolutionDropDownLabel = uilabel(app.GridLayout);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.GridLayout);
            app.ResolutionDropDown.Items = {'640x480', '800x600', '1280x720', '1920x1080'};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Value = '1280x720';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.GridLayout2 = uigridlayout(app.SegmentationTab);
            app.UIAxesPiece = uiaxes(app.GridLayout2);
            app.UIAxesSeg = uiaxes(app.GridLayout2);
            app.SegmentationLabel = uilabel(app.GridLayout2);
            app.SegmentationLabel.Text = 'Segmentation';
            app.FeaturesTab = uitab(app.TabGroup);
            app.FeaturesTab.Title = 'Features';
            app.UIAxesFeatures = uiaxes(app.FeaturesTab);
            app.MatchingFeaturesLabel = uilabel(app.FeaturesTab);
            app.MatchingFeaturesLabel.Text = 'Matching Features';
            app.CoordinateandRealibiltyLabel = uilabel(app.FeaturesTab);
            app.CoordinateandRealibiltyLabel.Text = 'Coordinate and Realibilty';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end