classdef GUI_interactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        SettingsButton      matlab.ui.control.Button
        Label               matlab.ui.control.Label
        NewGameButton       matlab.ui.control.Button
        FindthePieceButton  matlab.ui.control.Button
        PlayButton          matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam
        num_of_piece
        num_row
        num_col
    end
    
    properties (Access = public)
        img
        appSettings
        segParams=struct();
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, num_of_piece, num_row, num_col, img, segParams)
            app.num_of_piece = num_of_piece;
            app.num_row = num_row;
            app.num_col = num_col;
            app.img = img;
            app.segParams= segParams;

            app.FindthePieceButton.Enable = 'off';
            app.NewGameButton.Enable = 'off';
            app.segParams = segParams;
%             app.flag = 0;
            global flag_stop
            flag_stop = 0;
            app.UIAxes.Visible = 'off';
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.cam = webcam(2); %webcam(2)
            app.cam.FocusMode = 'manual';
            app.cam.ExposureMode = 'manual';
            frame = snapshot(app.cam);
            im = image(app.UIAxes, zeros(size(frame),'uint8'));
            axis(app.UIAxes,'image');
            preview(app.cam,im);
            app.UIAxes.Visible = 'on'; 
            app.FindthePieceButton.Enable = 'on';
            app.NewGameButton.Enable = 'on';
            msg = ['Arrange the puzzle pieces in front of the camera and' ...
                ' press the button when done'];
            uialert(app.UIFigure,msg,'The camera is playing','Icon', ...
                'success');
            app.SettingsButton.Enable = 'on';
%             app.appSettings.UIFigure.Visible = 'off';
        end

        % Button pushed function: FindthePieceButton
        function FindthePieceButtonPushed(app, event)
            solve_puzzle(app.num_of_piece,app.num_row,app.num_col,app.cam,app);
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
%             closepreview(app.cam);
            close all force;
%             app.flag = 1; % break from script while loop
            global flag_stop
            flag_stop = 1;
            Solving_puzzle_GUI; % return to main menu
            delete(app);
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            app.appSettings = GUI_Settings(app.cam,app);
%             app.appSettings.UIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FindthePieceButton = uibutton(app.GridLayout, 'push');
            app.FindthePieceButton.ButtonPushedFcn = createCallbackFcn(app, @FindthePieceButtonPushed, true);
            app.FindthePieceButton.Text = 'Find the Piece!';
            app.NewGameButton = uibutton(app.GridLayout, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.Label = uilabel(app.GridLayout);
            app.Label.Visible = 'off';
            app.SettingsButton = uibutton(app.GridLayout, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_interactive(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end