classdef Solving_puzzle_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        ExitButton          matlab.ui.control.Button
        Button_15           matlab.ui.control.Button
        Button_20           matlab.ui.control.Button
        Button_9            matlab.ui.control.Button
        Button_12           matlab.ui.control.Button
        SelectaPuzzleLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        num_of_piece
        num_row
        num_col
        img
%         resize_factor
        segParams = struct();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_12
        function Puzzle12Pushed(app, event)
            app.num_of_piece = 12;
            app.num_row = 3;
            app.num_col = 4;
            app.segParams.dial1 = 1;
            app.segParams.dial2 = 4;
            app.segParams.ext_filt = 0.7;
            app.segParams.center_size = 20;
            app.segParams.resize_factor = 8;
            app.img = "img\pzl_12_p3.jpg";
            GUI_interactive(app.num_of_piece,app.num_row,app.num_col,app.img,app.segParams);
            delete(app)
        end

        % Button pushed function: Button_9
        function Puzzle24Pushed(app, event)
            app.num_of_piece = 9; % 24;
            app.num_row = 3; % 4;
            app.num_col = 3; % 6;
            app.segParams.dial1 = 2;
            app.segParams.dial2 = 7;
            app.segParams.ext_filt = 1;
            app.segParams.center_size = 10;
            app.segParams.resize_factor = 6;
            app.img =  "img\pzl_9_p3.jpg"; % "img\pzl_24_p3.jpg";
            GUI_interactive(app.num_of_piece,app.num_row,app.num_col,app.img,app.segParams);
            delete(app)
        end

        % Button pushed function: Button_20
        function Puzzle20Pushed(app, event)
            app.num_of_piece = 20;
            app.num_row = 4;
            app.num_col = 5;
            app.segParams.dial1 = 1;
            app.segParams.dial2 = 3;
            app.segParams.ext_filt = 0.7;
            app.segParams.center_size = 50;
            app.segParams.resize_factor = 8;
            app.img = "img\pzl_20_p3.jpg";
            GUI_interactive(app.num_of_piece,app.num_row,app.num_col,app.img,app.segParams);
            delete(app)
        end

        % Button pushed function: Button_15
        function Puzzle15Pushed(app, event)
            app.num_of_piece = 15;
            app.num_row = 3;
            app.num_col = 5;

            app.segParams.dial1 = 2;
            app.segParams.dial2 = 4;
            app.segParams.ext_filt = 0.7;
            app.segParams.center_size =10;
            app.segParams.resize_factor = 8;
%             app.segParams.dial1 = 3;
%             app.segParams.dial2 = 8;
%             app.segParams.ext_filt = 0.7;
%             app.segParams.center_size =35;
            app.img = "img\pzl_15_p3.jpg";
            GUI_interactive(app.num_of_piece,app.num_row,app.num_col,app.img,app.segParams);
            delete(app)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectaPuzzleLabel = uilabel(app.GridLayout);
            app.SelectaPuzzleLabel.Text = 'Select a Puzzle';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Puzzle12Pushed, true);
            app.Button_12.Icon = 'pzl_12_p3.jpg';
            app.Button_12.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Puzzle24Pushed, true);
            app.Button_9.Icon = 'pzl_9_p3.jpg';
            app.Button_9.Text = '';
            app.Button_20 = uibutton(app.GridLayout, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Puzzle20Pushed, true);
            app.Button_20.Icon = 'pzl_20_p3.jpg';
            app.Button_20.Text = '';
            app.Button_15 = uibutton(app.GridLayout, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Puzzle15Pushed, true);
            app.Button_15.Icon = 'pzl_15_p3.jpg';
            app.Button_15.Text = '';
            app.ExitButton = uibutton(app.GridLayout, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Solving_puzzle_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end